classdef mineralization_tool_Investigation_mode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CheckthemanuscriptonLabel       matlab.ui.control.Label
        McGillUniversityLabel           matlab.ui.control.Label
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Label                           matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        BonemineralizationsimulationLabel  matlab.ui.control.Label
        ParameterspanelPanel            matlab.ui.container.Panel
        bLabel                          matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        r2Label                         matlab.ui.control.Label
        r2EditField                     matlab.ui.control.NumericEditField
        r2EditFieldLabel                matlab.ui.control.Label
        t1Label                         matlab.ui.control.Label
        t1EditField                     matlab.ui.control.NumericEditField
        t1EditFieldLabel                matlab.ui.control.Label
        r1Label                         matlab.ui.control.Label
        r1EditField                     matlab.ui.control.NumericEditField
        r1EditFieldLabel                matlab.ui.control.Label
        k1Label                         matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        k3Label                         matlab.ui.control.Label
        k3EditField                     matlab.ui.control.NumericEditField
        k3EditFieldLabel                matlab.ui.control.Label
        k2Label                         matlab.ui.control.Label
        k2EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        v1Label                         matlab.ui.control.Label
        v1EditField                     matlab.ui.control.NumericEditField
        v1EditFieldLabel                matlab.ui.control.Label
        x2Label                         matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        Y_cEditField                    matlab.ui.control.NumericEditField
        Y_cEditFieldLabel               matlab.ui.control.Label
        N_cEditField                    matlab.ui.control.NumericEditField
        N_cEditFieldLabel               matlab.ui.control.Label
        I_cEditField                    matlab.ui.control.NumericEditField
        I_cEditFieldLabel               matlab.ui.control.Label
        x2_cEditField                   matlab.ui.control.NumericEditField
        x2_cEditFieldLabel              matlab.ui.control.Label
        x1_cEditField                   matlab.ui.control.NumericEditField
        x1_cEditFieldLabel              matlab.ui.control.Label
        RateEditField                   matlab.ui.control.NumericEditField
        RateEditFieldLabel              matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        pHEditField                     matlab.ui.control.NumericEditField
        pHEditFieldLabel                matlab.ui.control.Label
        TKEditField                     matlab.ui.control.NumericEditField
        TKEditFieldLabel                matlab.ui.control.Label
        TClEditField                    matlab.ui.control.NumericEditField
        TClEditFieldLabel               matlab.ui.control.Label
        TNaEditField                    matlab.ui.control.NumericEditField
        TNaEditFieldLabel               matlab.ui.control.Label
        TMgEditField                    matlab.ui.control.NumericEditField
        TMgEditFieldLabel               matlab.ui.control.Label
        TCaEditField                    matlab.ui.control.NumericEditField
        TCaEditFieldLabel               matlab.ui.control.Label
        TPO4EditField                   matlab.ui.control.NumericEditField
        TPO4EditFieldLabel              matlab.ui.control.Label
        TCO3EditField                   matlab.ui.control.NumericEditField
        TCO3EditFieldLabel              matlab.ui.control.Label
        CharachteristicvaluesLabel      matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        x1Label                         matlab.ui.control.Label
        BiomineralizationParametersLabel  matlab.ui.control.Label
        RateUnitLabel                   matlab.ui.control.Label
        PhysicochemicalParametersLabel  matlab.ui.control.Label
        CommandpanelPanel               matlab.ui.container.Panel
        DurationdaysEditField           matlab.ui.control.EditField
        DurationdaysEditFieldLabel      matlab.ui.control.Label
        ExportoutputsLabel              matlab.ui.control.Label
        NondimensionalizedLabel         matlab.ui.control.Label
        ModelmodeLabel                  matlab.ui.control.Label
        ModelmodeSwitch                 matlab.ui.control.Switch
        ResetbioparamButton             matlab.ui.control.Button
        ResetphysparamButton            matlab.ui.control.Button
        ClearplotsButton                matlab.ui.control.Button
        ResetallvaluesButton            matlab.ui.control.Button
        ActiveCheckBox                  matlab.ui.control.CheckBox
        DimSwitch                       matlab.ui.control.Switch
        RunmineralizationButton         matlab.ui.control.Button
        RunISFButton                    matlab.ui.control.Button
        VisualizationpanelPanel         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % this function currently doesn't do anything, all happens under Deafault Value button
        function default_values = default_value_saver(app)
            pH_def = app.pHEditField.Value;
            TCO3_def = app.TCO3EditField.Value;
            TPO4_def = app.TPO4EditField.Value;
            TCa_def = app.TCaEditField.Value;
            TMg_def = app.TMgEditField.Value;
            TNa_def = app.TNaEditField.Value;
            TCl_def = app.TClEditField.Value;
            TK_def = app.TKEditField.Value;

            k1_def = app.k1EditField.Value;
            k2_def = app.k2EditField.Value;
            k3_def = app.k3EditField.Value;
            v1_def = app.v1EditField.Value;
            r1_def = app.r1EditField.Value;
            r2_def = app.r2EditField.Value;
            a_def = app.aEditField.Value;
            b_def = app.bEditField.Value;
            t1_def = app.t1EditField.Value;

            x1_c_def = app.x1_cEditField.Value; 
            x2_c_def = app.x1_cEditField.Value; %assumption
            I_c_def = app.I_cEditField.Value;
            N_c_def = app.N_cEditField.Value;
            y_c_def = app.Y_cEditField.Value;

            X1_def = app.X1EditField.Value;   
            X2_def = app.X2EditField.Value;  
            I_def = app.IEditField.Value;    
            N_def = app.NEditField.Value;    
            Y_def = app.YEditField.Value;  

            physico_values = [pH_def, TCO3_def, TPO4_def, TCa_def, TMg_def,...
                TNa_def, TCl_def, TK_def];
            bio_values_1 = [X1_def, X2_def, I_def,N_def,Y_def];
            bio_values_2 = [k1_def, k2_def, k3_def, v1_def,r1_def,r2_def,...
                a_def,b_def, t1_def];
            charac_value = [x1_c_def, x2_c_def, I_c_def, N_c_def, y_c_def]; 
            default_values = [physico_values, bio_values_1, bio_values_2, charac_value];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClearplotsButtonPushed(app);
            ResetallvaluesButtonPushed(app);
        end

        % Button pushed function: RunISFButton
        function RunISFButtonPushed(app, event)
            master_concentration = [app.pHEditField.Value, app.TCO3EditField.Value, app.TPO4EditField.Value...
                app.TCaEditField.Value, app.TMgEditField.Value, app.TNaEditField.Value, app.TClEditField.Value, ...
                app.TKEditField.Value];

            [~, ~, ~, ~, precipitation_rate]  = ISF_function(master_concentration, 1); % Poorhemati 2023

            % Convert k3 (precipitation rate) from ISF unit to biomin model unit:
            k3_raw = precipitation_rate * (24*60*60) * 6 * 10^8;  %molecule HAP /  (day . um^3)
            app.RateEditField.Value = k3_raw;
            app.RateUnitLabel.Text = 'Molecule HAP /  (day . um^3)';
            app.k3EditField.Value = app.RateEditField.Value;
        end

        % Button pushed function: RunmineralizationButton
        function RunmineralizationButtonPushed(app, event)
            %RunISFButtonPushed(app, event)
            app.k3EditField.Value = app.RateEditField.Value;
            [k1, k2, k3, v1, r1, r2, a, b, t1] = deal(app.k1EditField.Value,...
                app.k2EditField.Value, app.k3EditField.Value, ...
                app.v1EditField.Value, app.r1EditField.Value, ...
                app.r2EditField.Value, app.aEditField.Value, ...
                app.bEditField.Value, app.t1EditField.Value);

            % Nondimensialization - initial values
            p(1) = app.X1EditField.Value;   %  9.4 * 10^5; %x1 
            p(2) = app.X2EditField.Value;   %0;  %x2
            p(3) = app.IEditField.Value;    %10^6; %I
            p(4) = app.NEditField.Value;    %10; %N
            p(5) = app.YEditField.Value;    %0.8 * 10^9; %y
          
            % initial values non-dimensionalization            
            if app.DimSwitch.Value == "No"
                x1_c = 1; 
                x2_c = x1_c; % assumption
                I_c = 1;
                N_c = 1;
                y_c = 1;
            elseif app.DimSwitch.Value == "Yes"
                % Characteristics
                x1_c = app.x1_cEditField.Value; 
                x2_c = app.x2_cEditField.Value; % assumption
                I_c = app.I_cEditField.Value;
                N_c = app.N_cEditField.Value;
                y_c = app.Y_cEditField.Value;
            end

            
            inits = [p(1)/x1_c, p(2)/x2_c, p(3)/I_c, p(4)/N_c, p(5)/y_c]; %X1, X2, I, N, Y
            characteristics = [x1_c, x2_c, I_c, N_c, y_c];

            % Dispalying the non_D values to user
            app.x1Label.Text = num2str(inits(1)); 
            app.x2Label.Text = num2str(inits(2)); 
            app.ILabel.Text = num2str(inits(3)); 
            app.NLabel.Text = num2str(inits(4)); 
            app.YLabel.Text = num2str(inits(5)); 

            % Nondimensialization - parameters

            [k1_nonD, k2_nonD, k3_nonD, v1_nonD, r1_nonD, r2_nonD, a_nonD, b_nonD, t1_nonD] ...
                = non_dimensionalizer('K3_IR_v3_2', k1, k2, k3, v1, r1, r2, a, b, t1, characteristics);

            [k1, k2, k3, v1, r1, r2, a, b, t1] = deal(k1_nonD, k2_nonD, ...
                k3_nonD, v1_nonD, r1_nonD, r2_nonD, a_nonD, b_nonD, t1_nonD);

            app.k1Label.Text = num2str(k1_nonD);
            app.v1Label.Text = num2str(v1_nonD);
            app.r1Label.Text = num2str(r1_nonD);
            app.r2Label.Text = num2str(r2_nonD);
            app.k2Label.Text = num2str(k2_nonD);
            app.k3Label.Text = num2str(k3_nonD);
            app.aLabel.Text = num2str(a_nonD);
            app.bLabel.Text = num2str(b_nonD);
            app.t1Label.Text = num2str(t1_nonD);

            duration = str2double(app.DurationdaysEditField.Value);
            
            % Inhibitor improvement state: 23_[K3]_IR...
            [T,Y] = model_database('K3_IR_v3_2', k1, k2, k3, v1, r1, r2, a, b, t1, inits, duration);

            % Finding lag time
            [peaks_locs, transition_point] = lag_time_calc(T,Y(:,5));
            peaks = peaks_locs(:,1);
            locs = peaks_locs(:,2);

            % Plot mineralization
            hold(app.UIAxes_2, 'on')
            plot(app.UIAxes_2, T,Y(:,5),'LineWidth',1);
            scatter(app.UIAxes_2, T(locs+1), Y(locs+1, 5) , "filled", "DisplayName", string(transition_point)); 
            text(app.UIAxes_2, T(locs+1), Y(locs+1, 5) , string(round(T(locs+1))), 'VerticalAlignment', 'top');
            app.UIAxes_2.XLimMode = 'auto';
            app.UIAxes_2.YLimMode = 'auto';
            %legend(app.UIAxes_2);

            hold(app.UIAxes, 'on')
            plot(app.UIAxes, T,(Y(:,5)/max(Y(:,5))),'LineWidth',1, "DisplayName", "Mineral");
            [peaks_locs_2, transition_point_2] = lag_time_calc(T,(Y(:,5)/max(Y(:,5))));
            peaks_2 = peaks_locs_2(:,1);
            locs_2 = peaks_locs_2(:,2);
            scatter(app.UIAxes, transition_point_2, (Y(locs_2(1)+1, 5)/max(Y(:,5))) , "filled", "DisplayName", string(transition_point)); 
            text(app.UIAxes, transition_point_2, Y(locs_2(1)+1, 5) , string(round(transition_point)), 'VerticalAlignment', 'top');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            %legend(app.UIAxes);
            hold(app.UIAxes, 'off')

            % plot the rest 
            % plot of inhibitors - normalized:
            hold(app.UIAxes_3, 'on')
            plot(app.UIAxes_3,T,(Y(:,3)/max(Y(:,3))),'LineWidth',1);
            app.UIAxes_3.XLimMode = 'auto';
            app.UIAxes_3.YLimMode = 'auto';
            % plot of inhibitors - non-normalized:
            hold(app.UIAxes_8, 'on')
            plot(app.UIAxes_8,T,Y(:,3),'LineWidth',1) ;
            app.UIAxes_8.XLimMode = 'auto';
            app.UIAxes_8.YLimMode = 'auto';
            
            % plot of nucleators - normalized:
            hold(app.UIAxes_4, 'on')
            plot(app.UIAxes_4,T,(Y(:,4)/max(Y(:,4))),'LineWidth',1) ;
            app.UIAxes_4.XLimMode = 'auto';
            app.UIAxes_4.YLimMode = 'auto';
            % plot of nucleators - non-normalized:
            hold(app.UIAxes_7, 'on')
            plot(app.UIAxes_7,T,Y(:,4),'LineWidth',1);
            app.UIAxes_7.XLimMode = 'auto';
            app.UIAxes_7.YLimMode = 'auto';

            % plot of collagens - normalized:
            hold(app.UIAxes_5, 'on')
            plot(app.UIAxes_5,T,(Y(:,1)/max(Y(:,1))),'LineWidth',1) ;
            plot(app.UIAxes_5,T,(Y(:,2)/max(Y(:,2))),'LineWidth',1) ;
            app.UIAxes_5.XLimMode = 'auto';
            app.UIAxes_5.YLimMode = 'auto';
            
            % plot all together
            hold(app.UIAxes_9, 'on')
            plot(app.UIAxes_9, T,(Y(:,1)/max(Y(:,1))),'LineWidth',1);
            plot(app.UIAxes_9, T,(Y(:,2)/max(Y(:,2))),'LineWidth',1);
            plot(app.UIAxes_9, T,(Y(:,3)/max(Y(:,3))),'LineWidth',1);
            plot(app.UIAxes_9, T,(Y(:,4)/max(Y(:,4))),'LineWidth',1);
            plot(app.UIAxes_9, T,(Y(:,5)/max(Y(:,5))),'LineWidth',1);
            %legend(app.UIAxes_9);
            hold(app.UIAxes_9, 'off')
            app.UIAxes_9.XLimMode = 'auto';
            app.UIAxes_9.YLimMode = 'auto';
 
            % Use the gradient function to calculate derivatives
            %dydt_values = gradient(Y(:,5), T);
            dy_dx = diff(Y(:,5)) ./ diff(T);
            hold(app.UIAxes_6, 'on')
            plot(app.UIAxes_6, T(2:end), dy_dx,'LineWidth',1);
            scatter(app.UIAxes_6,T(locs(1)+1), dy_dx(locs) ,'r', 'filled'); 
            app.UIAxes_6.XLimMode = 'auto';
            app.UIAxes_6.YLimMode = 'auto';
            
            if app.ActiveCheckBox.Value == 1    
                ISF_info_item = ["TCO3","TPO4","TCa","TMg","TNa","TCl","TK","pH",];
                ISF_info_value = [app.TCO3EditField.Value, app.TPO4EditField.Value, ...
                    app.TCaEditField.Value, app.TMgEditField.Value, app.TNaEditField.Value, ...
                    app.TClEditField.Value, app.TKEditField.Value, app.pHEditField.Value];
                ISF_info = [ISF_info_item', ISF_info_value'];
                
                Bio_info_item = ["x1_0", "x2_0", "I_0", "N_0", "y_0", "k1",...
                    "k2", "k3", "v1", "r1", "r2", "t1", "a", "b"];
                Bio_info_value = [ inits ,k1, k2, k3, v1, r1, r2, t1, a, b];
                Bio_info = [Bio_info_item', Bio_info_value'];
                lag_info = [T(locs+1), Y(locs+1, 5)];

                filename = 'data.xlsx';              
                sheetname = datestr(datetime('now'), 'yyyymmdd_HHMMSS');                
                
                xlswrite(filename, ["Mode:",app.DimSwitch.Value], sheetname, "A1");

                xlswrite(filename, ["ISF model Parameters:",""], sheetname, "A3");
                xlswrite(filename, ISF_info, sheetname, "A4");

                xlswrite(filename, ["Biological model Parameters:",""], sheetname, "A15");
                xlswrite(filename, Bio_info, sheetname, "A16");

                xlswrite(filename, ["Lag time info: [t, Y]",""], sheetname, "A32");
                xlswrite(filename, lag_info, sheetname, "A33");
                
                xlswrite(filename, ["t", "x1", "x2", "I", "N", "y"], sheetname, "D1");
                xlswrite(filename, [T,Y], sheetname, "D2");

                xlswrite(filename, ["dy/dt",""], sheetname, "J1");
                xlswrite(filename, dy_dx, sheetname, "J2");             
            end
        end

        % Button pushed function: ClearplotsButton
        function ClearplotsButtonPushed(app, event)
            cla(app.UIAxes_2);
            %app.UIAxes_2.YLim = [-0.2,1.2];
            cla(app.UIAxes);
            %app.UIAxes.YLim = [-0.2,1.2];
            cla(app.UIAxes_3);
            %app.UIAxes_3.YLim = [-0.1,1.2];
            cla(app.UIAxes_4);
            %app.UIAxes_4.YLim = [-0.1,1.2];
            cla(app.UIAxes_5);
            %app.UIAxes_5.YLim = [-0.1,1.2];
            cla(app.UIAxes_6);
            %app.UIAxes_6.YLim = [-0.1,1.2];
            cla(app.UIAxes_7);
            %app.UIAxes_7.YLim = [-0.1,1.2];
            cla(app.UIAxes_8);
            %app.UIAxes_8.YLim = [-0.1,1.2];
            cla(app.UIAxes_9);
            %app.UIAxes_9.YLim = [-0.1,1.2];
            
        end

        % Button pushed function: ResetallvaluesButton
        function ResetallvaluesButtonPushed(app, event)
            
            % ISF values
            app.pHEditField.Value = 7.4;
            app.TCO3EditField.Value = 0.027;
            app.TPO4EditField.Value = 0.001;
            app.TCaEditField.Value = 0.0016; 
            app.TMgEditField.Value = 0.001;
            app.TNaEditField.Value = 0.142;
            app.TClEditField.Value = 0.103;
            app.TKEditField.Value = 0.005;
            
            % Biomin parameter values
            app.k1EditField.Value = 0.3;
            app.k2EditField.Value = 0.005;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.005;
            app.r2EditField.Value = 5e-10;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            if app.ModelmodeSwitch.Value == "Primary"
                app.t1EditField.Value = 0;
                app.r1EditField.Value = 1e-7;%5e-8;
            else
                app.t1EditField.Value = 8e-9; %1e-9;
                app.r1EditField.Value = 0;
            end 

            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 9.4e5;    
            app.NEditField.Value = 1; 
            app.YEditField.Value = 0; 
            
            % characteristic values
            app.x1_cEditField.Value = 10^6; 
            app.x2_cEditField.Value = app.x1_cEditField.Value; %assumption
            app.I_cEditField.Value = 10^6;
            app.N_cEditField.Value = 10^6;
            app.Y_cEditField.Value = 10^9;


           % old default values
            %             % Biomin parameter values
            % app.k1EditField.Value = 0.1;
            % app.k2EditField.Value = 1;
            % app.k3EditField.Value = 292855.1449679231;
            % app.v1EditField.Value = 0.1;
            % app.r1EditField.Value = 2e-7;
            % app.r2EditField.Value = 1.7e-8;
            % app.aEditField.Value = 10;
            % app.bEditField.Value = 1e57;
            % app.t1EditField.Value = 1.5e-8;
            % 
            % % Biomin variable initial values
            % app.X1EditField.Value = 9.4e5;   
            % app.X2EditField.Value = 0;  
            % app.IEditField.Value = 9.4e5;    
            % app.NEditField.Value = 10;  % 1-10 per assembled collagen  
            % app.YEditField.Value = 0; 
            % 
            % % characteristic values
            % app.x1_cEditField.Value = 10^6; 
            % app.x2_cEditField.Value = app.x1_cEditField.Value; %assumption
            % app.I_cEditField.Value = 10^6;
            % app.N_cEditField.Value = 10^6;
            % app.Y_cEditField.Value = 10^9;
        end

        % Callback function: not associated with a component
        function combin1ButtonPushed(app, event)
            % Biomin parameter values
            % app.k1EditField.Value = 0.4;
            % app.k2EditField.Value = 0.0005;
            % app.k3EditField.Value = 292855.1449679231;
            % app.v1EditField.Value = 0.01;
            % app.r1EditField.Value = 5e-8;
            % app.r2EditField.Value = 5e-9;
            % app.aEditField.Value = 10;
            % app.bEditField.Value = 1e57;
            % app.t1EditField.Value = 5e-9;

            % % Biomin variable initial values
            % app.X1EditField.Value = 9.4e5;   
            % app.X2EditField.Value = 0;  
            % app.IEditField.Value = 9.4e5;    
            % app.NEditField.Value = 10;    
            % app.YEditField.Value = 0; 
        end

        % Callback function: not associated with a component
        function ManuscriptvaluesButtonPushed(app, event)
            % Biomin parameter values
            app.k1EditField.Value = 0.3;
            app.k2EditField.Value = 0.005;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.005;
            app.r2EditField.Value = 5e-10;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            if app.ModelmodeSwitch.Value == "Primary"
                app.t1EditField.Value = 0;
                app.r1EditField.Value = 1e-7;%5e-8;
            else
                app.t1EditField.Value = 8e-9; %1e-9;
                app.r1EditField.Value = 0;
            end 

            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 9.4e5;    
            app.NEditField.Value = 10;    
            app.YEditField.Value = 0; 
        end

        % Callback function: not associated with a component
        function PrimaryModet10ButtonPushed(app, event)
            app.t1EditField.Value = 0;
            app.r1EditField.Value = 1e-7;
        end

        % Callback function: not associated with a component
        function EnhancedModet10ButtonPushed(app, event)
            app.t1EditField.Value = 8e-9;
            app.r1EditField.Value = 0;
        end

        % Button pushed function: ResetphysparamButton
        function ResetphysparamButtonPushed(app, event)

            % ISF values
            app.pHEditField.Value = 7.4;
            app.TCO3EditField.Value = 0.027;
            app.TPO4EditField.Value = 0.001;
            app.TCaEditField.Value = 0.0016; 
            app.TMgEditField.Value = 0.001;
            app.TNaEditField.Value = 0.142;
            app.TClEditField.Value = 0.103;
            app.TKEditField.Value = 0.005;
            
        end

        % Button pushed function: ResetbioparamButton
        function ResetbioparamButtonPushed(app, event)
            
            % Biomin parameter values
            app.k1EditField.Value = 0.3;
            app.k2EditField.Value = 0.005;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.005;
            app.r2EditField.Value = 5e-10;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            if app.ModelmodeSwitch.Value == "Primary"
                app.t1EditField.Value = 0;
                app.r1EditField.Value = 1e-7;%5e-8;
            else
                app.t1EditField.Value = 8e-9; %1e-9;
                app.r1EditField.Value = 0;
            end 

            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 9.4e5;    
            app.NEditField.Value = 1; 
            app.YEditField.Value = 0; 
            
            % characteristic values
            app.x1_cEditField.Value = 10^6; 
            app.x2_cEditField.Value = app.x1_cEditField.Value; %assumption
            app.I_cEditField.Value = 10^6;
            app.N_cEditField.Value = 10^6;
            app.Y_cEditField.Value = 10^9;

        end

        % Value changed function: ModelmodeSwitch
        function ModelmodeSwitchValueChanged(app, event)
            value = app.ModelmodeSwitch.Value;
            if value == "Primary"
                app.t1EditField.Value = 0;
                app.r1EditField.Value = 1e-7; %5e-8;
            else
                app.t1EditField.Value = 8e-9; %1e-9;
                app.r1EditField.Value = 0;
            end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizationpanelPanel = uipanel(app.UIFigure);
            app.VisualizationpanelPanel.Title = 'Visualization panel';
            app.UIAxes_9 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_9, 'All (Normalized)')
            xlabel(app.UIAxes_9, 'Day')
            ylabel(app.UIAxes_9, 'N')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_8 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_8, 'Inhibotors')
            xlabel(app.UIAxes_8, 'Day')
            ylabel(app.UIAxes_8, 'I')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_7 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_7, 'Nucleators')
            xlabel(app.UIAxes_7, 'Day')
            ylabel(app.UIAxes_7, 'N')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_6 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_6, 'dY/dt')
            xlabel(app.UIAxes_6, 'Day')
            ylabel(app.UIAxes_6, 'dy/dt')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_5, 'Collagen')
            xlabel(app.UIAxes_5, 'Day')
            ylabel(app.UIAxes_5, 'Xi')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_4, 'Nucleators (Normalized)')
            xlabel(app.UIAxes_4, 'Day')
            ylabel(app.UIAxes_4, 'N')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_3, 'Inhibotors (Normalized)')
            xlabel(app.UIAxes_3, 'Day')
            ylabel(app.UIAxes_3, 'I')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes_2, 'Mineralization')
            xlabel(app.UIAxes_2, 'Day')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.VisualizationpanelPanel);
            title(app.UIAxes, 'Mineralization (Normalized)')
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CommandpanelPanel = uipanel(app.UIFigure);
            app.CommandpanelPanel.Title = 'Command panel';
            app.RunISFButton = uibutton(app.CommandpanelPanel, 'push');
            app.RunISFButton.ButtonPushedFcn = createCallbackFcn(app, @RunISFButtonPushed, true);
            app.RunISFButton.Tooltip = {'Runs the physicochemical compartment, calculating the maximum precipitation rate'};
            app.RunISFButton.Text = 'Run ISF';
            app.RunmineralizationButton = uibutton(app.CommandpanelPanel, 'push');
            app.RunmineralizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunmineralizationButtonPushed, true);
            app.RunmineralizationButton.Tooltip = {'Runs the mineralization simulation (remember to push Run ISF first)'};
            app.RunmineralizationButton.Text = 'Run mineralization';
            app.DimSwitch = uiswitch(app.CommandpanelPanel, 'slider');
            app.DimSwitch.Items = {'Yes', 'No'};
            app.DimSwitch.Tooltip = {'In non-dim mode, the values are devided by the characteristic values.'};
            app.DimSwitch.Value = 'Yes';
            app.ActiveCheckBox = uicheckbox(app.CommandpanelPanel);
            app.ActiveCheckBox.Tooltip = {'Check to export data after each run. Makes a new tab on excel output file.'};
            app.ActiveCheckBox.Text = 'Active';
            app.ResetallvaluesButton = uibutton(app.CommandpanelPanel, 'push');
            app.ResetallvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallvaluesButtonPushed, true);
            app.ResetallvaluesButton.Tooltip = {'Reset all values to the default values'};
            app.ResetallvaluesButton.Text = 'Reset all values';
            app.ClearplotsButton = uibutton(app.CommandpanelPanel, 'push');
            app.ClearplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotsButtonPushed, true);
            app.ClearplotsButton.Tooltip = {'Clear all plots'};
            app.ClearplotsButton.Text = 'Clear plots';
            app.ResetphysparamButton = uibutton(app.CommandpanelPanel, 'push');
            app.ResetphysparamButton.ButtonPushedFcn = createCallbackFcn(app, @ResetphysparamButtonPushed, true);
            app.ResetphysparamButton.Tooltip = {'Reset only the physicochemical parameters to defaults'};
            app.ResetphysparamButton.Text = 'Reset phys. param.';
            app.ResetbioparamButton = uibutton(app.CommandpanelPanel, 'push');
            app.ResetbioparamButton.ButtonPushedFcn = createCallbackFcn(app, @ResetbioparamButtonPushed, true);
            app.ResetbioparamButton.Tooltip = {'Reset only the biological values to defaults'};
            app.ResetbioparamButton.Text = 'Reset bio. param.';
            app.ModelmodeSwitch = uiswitch(app.CommandpanelPanel, 'slider');
            app.ModelmodeSwitch.Items = {'Primary', 'Enhanced'};
            app.ModelmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModelmodeSwitchValueChanged, true);
            app.ModelmodeSwitch.Tooltip = {'In enhanced mode, inhibitor removal is (also) associated with mineral formation rate. Refer to the result section of the manuscript. '};
            app.ModelmodeSwitch.Value = 'Primary';
            app.ModelmodeLabel = uilabel(app.CommandpanelPanel);
            app.ModelmodeLabel.Text = 'Model mode:';
            app.NondimensionalizedLabel = uilabel(app.CommandpanelPanel);
            app.NondimensionalizedLabel.Text = 'Non-dimensionalized:';
            app.ExportoutputsLabel = uilabel(app.CommandpanelPanel);
            app.ExportoutputsLabel.Text = 'Export outputs:';
            app.DurationdaysEditFieldLabel = uilabel(app.CommandpanelPanel);
            app.DurationdaysEditFieldLabel.Text = 'Duration (days)';
            app.DurationdaysEditField = uieditfield(app.CommandpanelPanel, 'text');
            app.DurationdaysEditField.InputType = 'digits';
            app.DurationdaysEditField.Value = '80';
            app.ParameterspanelPanel = uipanel(app.UIFigure);
            app.ParameterspanelPanel.Title = 'Parameters panel';
            app.PhysicochemicalParametersLabel = uilabel(app.ParameterspanelPanel);
            app.PhysicochemicalParametersLabel.Text = 'Physicochemical Parameters';
            app.RateUnitLabel = uilabel(app.ParameterspanelPanel);
            app.RateUnitLabel.Text = 'Rate Unit';
            app.BiomineralizationParametersLabel = uilabel(app.ParameterspanelPanel);
            app.BiomineralizationParametersLabel.Text = 'Biomineralization Parameters';
            app.x1Label = uilabel(app.ParameterspanelPanel);
            app.x1Label.Text = 'x1';
            app.ILabel = uilabel(app.ParameterspanelPanel);
            app.ILabel.Text = 'I';
            app.NLabel = uilabel(app.ParameterspanelPanel);
            app.NLabel.Text = 'N';
            app.YLabel = uilabel(app.ParameterspanelPanel);
            app.YLabel.Text = 'Y';
            app.CharachteristicvaluesLabel = uilabel(app.ParameterspanelPanel);
            app.CharachteristicvaluesLabel.Text = 'Charachteristic values';
            app.TCO3EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TCO3EditFieldLabel.Text = 'TCO3';
            app.TCO3EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TCO3EditField.Value = 0.027;
            app.TPO4EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TPO4EditFieldLabel.Text = 'TPO4';
            app.TPO4EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TPO4EditField.Value = 0.001;
            app.TCaEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TCaEditFieldLabel.Text = 'TCa';
            app.TCaEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TCaEditField.Value = 0.0016;
            app.TMgEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TMgEditFieldLabel.Text = 'TMg';
            app.TMgEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TMgEditField.Value = 0.001;
            app.TNaEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TNaEditFieldLabel.Text = 'TNa';
            app.TNaEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TNaEditField.Value = 0.142;
            app.TClEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TClEditFieldLabel.Text = 'TCl';
            app.TClEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TClEditField.Value = 0.103;
            app.TKEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.TKEditFieldLabel.Text = 'TK';
            app.TKEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.TKEditField.Value = 0.005;
            app.pHEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.pHEditField.Value = 7.4;
            app.X1EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.X1EditField.Value = 940000;
            app.IEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.IEditField.Value = 1000000;
            app.NEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.NEditField.Value = 10;
            app.YEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.RateEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.RateEditFieldLabel.Text = 'Rate ';
            app.RateEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.x1_cEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.x1_cEditFieldLabel.Text = 'x1_c';
            app.x1_cEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.x1_cEditField.Value = 1000000;
            app.x2_cEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.x2_cEditFieldLabel.Text = 'x2_c';
            app.x2_cEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.x2_cEditField.Value = 1000000;
            app.I_cEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.I_cEditFieldLabel.Text = 'I_c';
            app.I_cEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.I_cEditField.Value = 1000000;
            app.N_cEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.N_cEditFieldLabel.Text = 'N_c';
            app.N_cEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.N_cEditField.Value = 10000;
            app.Y_cEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.Y_cEditFieldLabel.Text = 'Y_c';
            app.Y_cEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.Y_cEditField.Value = 10000000000;
            app.X2EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.x2Label = uilabel(app.ParameterspanelPanel);
            app.x2Label.Text = 'x2';
            app.v1EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.v1EditFieldLabel.Text = 'v1';
            app.v1EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.v1EditField.Value = 0.1;
            app.v1Label = uilabel(app.ParameterspanelPanel);
            app.v1Label.Text = 'v1';
            app.k2EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.k2EditField.Value = 1;
            app.k2Label = uilabel(app.ParameterspanelPanel);
            app.k2Label.Text = 'k2';
            app.k3EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.k3EditFieldLabel.Text = 'k3';
            app.k3EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.k3Label = uilabel(app.ParameterspanelPanel);
            app.k3Label.Text = 'k3';
            app.k1EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.k1EditField.Value = 0.1;
            app.k1Label = uilabel(app.ParameterspanelPanel);
            app.k1Label.Text = 'k1';
            app.r1EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.r1EditField.Value = 2e-07;
            app.r1Label = uilabel(app.ParameterspanelPanel);
            app.r1Label.Text = 'r1';
            app.t1EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.t1EditFieldLabel.Text = 't1';
            app.t1EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.t1EditField.Value = 2e-07;
            app.t1Label = uilabel(app.ParameterspanelPanel);
            app.t1Label.Text = 't1';
            app.r2EditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.r2EditFieldLabel.Text = 'r2';
            app.r2EditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.r2EditField.Value = 1.7e-08;
            app.r2Label = uilabel(app.ParameterspanelPanel);
            app.r2Label.Text = 'r2';
            app.aEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.aEditField.Value = 10;
            app.aLabel = uilabel(app.ParameterspanelPanel);
            app.aLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.ParameterspanelPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ParameterspanelPanel, 'numeric');
            app.bEditField.Value = 1e+57;
            app.bLabel = uilabel(app.ParameterspanelPanel);
            app.bLabel.Text = 'b';
            app.BonemineralizationsimulationLabel = uilabel(app.UIFigure);
            app.BonemineralizationsimulationLabel.Text = 'Bone mineralization simulation';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://rdcu.be/d5rzb';
            app.Hyperlink.Text = 'Scientific Reports (Click here)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This tool simulates the bone mineralization process, incorporating both physicochemical and biological regulatory mechanisms. For detailed information about the model, its parameters, and interpretation, please refer to the manuscript. If you have any questions, feel free to contact the authors.';
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.URL = 'https://www.linkedin.com/in/hossein-poorhemati/';
            app.Hyperlink2.Text = 'H Poorhemati,';
            app.Hyperlink2_2 = uihyperlink(app.UIFigure);
            app.Hyperlink2_2.URL = 'https://www.linkedin.com/in/svetlana-komarova-b3a101148?lipi=urn%3Ali%3Apage%3Ad_flagship3_profile_view_base_contact_details%3B7oBSQVSsS1ObVKLYTzbUsA%3D%3D';
            app.Hyperlink2_2.Text = 'SV Komarova,';
            app.McGillUniversityLabel = uilabel(app.UIFigure);
            app.McGillUniversityLabel.Text = ' 2024 - McGill University';
            app.CheckthemanuscriptonLabel = uilabel(app.UIFigure);
            app.CheckthemanuscriptonLabel.Text = 'Check the manuscript on ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mineralization_tool_Investigation_mode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end