classdef mineralization_tool_Investigation_mode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Nor1Button                      matlab.ui.control.Button
        Not2Button                      matlab.ui.control.Button
        ExportdataaftereachrunCheckBox  matlab.ui.control.CheckBox
        ChosenmodeButton                matlab.ui.control.Button
        combin1Button                   matlab.ui.control.Button
        t2Label                         matlab.ui.control.Label
        t2EditField                     matlab.ui.control.NumericEditField
        t2EditFieldLabel                matlab.ui.control.Label
        Y_cEditField                    matlab.ui.control.NumericEditField
        Y_cEditFieldLabel               matlab.ui.control.Label
        N_cEditField                    matlab.ui.control.NumericEditField
        N_cEditFieldLabel               matlab.ui.control.Label
        I_cEditField                    matlab.ui.control.NumericEditField
        I_cEditFieldLabel               matlab.ui.control.Label
        ModelSelectionButtonGroup       matlab.ui.container.ButtonGroup
        K3_IR_v3_2Button                matlab.ui.control.RadioButton
        DimSwitch                       matlab.ui.control.Switch
        DefaultValuesButton             matlab.ui.control.Button
        ClearplotsButton                matlab.ui.control.Button
        bLabel                          matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        k3Label                         matlab.ui.control.Label
        r2Label                         matlab.ui.control.Label
        k2Label                         matlab.ui.control.Label
        r1Label                         matlab.ui.control.Label
        v1Label                         matlab.ui.control.Label
        k1Label                         matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        x2Label                         matlab.ui.control.Label
        x1Label                         matlab.ui.control.Label
        x2_cEditField                   matlab.ui.control.NumericEditField
        x2_cEditFieldLabel              matlab.ui.control.Label
        x1_cEditField                   matlab.ui.control.NumericEditField
        x1_cEditFieldLabel              matlab.ui.control.Label
        RunmineralizationButton         matlab.ui.control.Button
        RateEditField                   matlab.ui.control.NumericEditField
        RateEditFieldLabel              matlab.ui.control.Label
        RateUnitLabel                   matlab.ui.control.Label
        RunISFButton                    matlab.ui.control.Button
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        k3EditField                     matlab.ui.control.NumericEditField
        k3EditFieldLabel                matlab.ui.control.Label
        r2EditField                     matlab.ui.control.NumericEditField
        r2EditFieldLabel                matlab.ui.control.Label
        k2EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        r1EditField                     matlab.ui.control.NumericEditField
        r1EditFieldLabel                matlab.ui.control.Label
        v1EditField                     matlab.ui.control.NumericEditField
        v1EditFieldLabel                matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        pHEditField                     matlab.ui.control.NumericEditField
        pHEditFieldLabel                matlab.ui.control.Label
        TKEditField                     matlab.ui.control.NumericEditField
        TKEditFieldLabel                matlab.ui.control.Label
        TClEditField                    matlab.ui.control.NumericEditField
        TClEditFieldLabel               matlab.ui.control.Label
        TNaEditField                    matlab.ui.control.NumericEditField
        TNaEditFieldLabel               matlab.ui.control.Label
        TMgEditField                    matlab.ui.control.NumericEditField
        TMgEditFieldLabel               matlab.ui.control.Label
        TCaEditField                    matlab.ui.control.NumericEditField
        TCaEditFieldLabel               matlab.ui.control.Label
        TPO4EditField                   matlab.ui.control.NumericEditField
        TPO4EditFieldLabel              matlab.ui.control.Label
        TCO3EditField                   matlab.ui.control.NumericEditField
        TCO3EditFieldLabel              matlab.ui.control.Label
        BiomineralizationParametersLabel  matlab.ui.control.Label
        PhysicochemicalParametersLabel  matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % this function currently doesn't do anything, all happens under Deafault Value button
        function default_values = default_value_saver(app)
            pH_def = app.pHEditField.Value;
            TCO3_def = app.TCO3EditField.Value;
            TPO4_def = app.TPO4EditField.Value;
            TCa_def = app.TCaEditField.Value;
            TMg_def = app.TMgEditField.Value;
            TNa_def = app.TNaEditField.Value;
            TCl_def = app.TClEditField.Value;
            TK_def = app.TKEditField.Value;

            k1_def = app.k1EditField.Value;
            k2_def = app.k2EditField.Value;
            k3_def = app.k3EditField.Value;
            v1_def = app.v1EditField.Value;
            r1_def = app.r1EditField.Value;
            r2_def = app.r2EditField.Value;
            a_def = app.aEditField.Value;
            b_def = app.bEditField.Value;
            t2_def = app.t2EditField.Value;

            x1_c_def = app.x1_cEditField.Value; 
            x2_c_def = app.x1_cEditField.Value; %assumption
            I_c_def = app.I_cEditField.Value;
            N_c_def = app.N_cEditField.Value;
            y_c_def = app.Y_cEditField.Value;


            X1_def = app.X1EditField.Value;   
            X2_def = app.X2EditField.Value;  
            I_def = app.IEditField.Value;    
            N_def = app.NEditField.Value;    
            Y_def = app.YEditField.Value;  

            physico_values = [pH_def, TCO3_def, TPO4_def, TCa_def, TMg_def,...
                TNa_def, TCl_def, TK_def];
            bio_values_1 = [X1_def, X2_def, I_def,N_def,Y_def];
            bio_values_2 = [k1_def, k2_def, k3_def, v1_def,r1_def,r2_def,...
                a_def,b_def, t2_def];
            charac_value = [x1_c_def, x2_c_def, I_c_def, N_c_def, y_c_def];
            
            default_values = [physico_values, bio_values_1, bio_values_2, charac_value];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClearplotsButtonPushed(app);
            DefaultValuesButtonPushed(app);
                        
            
        end

        % Button pushed function: RunISFButton
        function RunISFButtonPushed(app, event)

            master_concentration = [app.pHEditField.Value, app.TCO3EditField.Value, app.TPO4EditField.Value...
                app.TCaEditField.Value, app.TMgEditField.Value, app.TNaEditField.Value, app.TClEditField.Value, ...
                app.TKEditField.Value];

            [~, ~, ~, ~, precipitation_rate]  = ISF_function(master_concentration, 1); % Poorhemati 2023

            % Convert k3 (precipitation rate) from ISF unit to biomin model unit:
            k3_raw = precipitation_rate * (24*60*60) * 6 * 10^8;  %molecule HAP /  (day . um^3)
            app.RateEditField.Value = k3_raw;
            app.RateUnitLabel.Text = 'Molecule HAP /  (day . um^3)';
            app.k3EditField.Value = app.RateEditField.Value;
        end

        % Button pushed function: RunmineralizationButton
        function RunmineralizationButtonPushed(app, event)
            %RunISFButtonPushed(app, event)
            app.k3EditField.Value = app.RateEditField.Value;
            [k1, k2, k3, v1, r1, r2, a, b, t2] = deal(app.k1EditField.Value,...
                app.k2EditField.Value, app.k3EditField.Value, ...
                app.v1EditField.Value, app.r1EditField.Value, ...
                app.r2EditField.Value, app.aEditField.Value, ...
                app.bEditField.Value, app.t2EditField.Value);

            % Nondimensialization - initial values
            p(1) = app.X1EditField.Value;   %  9.4 * 10^5; %x1 
            p(2) = app.X2EditField.Value;   %0;  %x2
            p(3) = app.IEditField.Value;    %10^6; %I
            p(4) = app.NEditField.Value;    %10; %N
            p(5) = app.YEditField.Value;    %0.8 * 10^9; %y
          
            % initial values non-dimensionalization            
            if app.DimSwitch.Value == "Dim"
                x1_c = 1; 
                x2_c = x1_c; % assumption
                I_c = 1;
                N_c = 1;
                y_c = 1;
            elseif app.DimSwitch.Value == "Non-dim"
                % Characteristics
                x1_c = app.x1_cEditField.Value; 
                x2_c = app.x2_cEditField.Value; % assumption
                I_c = app.I_cEditField.Value;
                N_c = app.N_cEditField.Value;
                y_c = app.Y_cEditField.Value;
            end

            inits = [p(1)/x1_c, p(2)/x2_c, p(3)/I_c, p(4)/N_c, p(5)/y_c]; %X1, X2, I, N, Y
            characteristics = [x1_c, x2_c, I_c, N_c, y_c];


            % Dispalying the non_D values to user
            app.x1Label.Text = num2str(inits(1)); 
            app.x2Label.Text = num2str(inits(2)); 
            app.ILabel.Text = num2str(inits(3)); 
            app.NLabel.Text = num2str(inits(4)); 
            app.YLabel.Text = num2str(inits(5)); 


            % Nondimensialization - parameters

            [k1_nonD, k2_nonD, k3_nonD, v1_nonD, r1_nonD, r2_nonD, a_nonD, b_nonD, t2_nonD] ...
                = non_dimensionalizer('K3_IR_v3_2', k1, k2, k3, v1, r1, r2, a, b, t2, characteristics);


            [k1, k2, k3, v1, r1, r2, a, b, t2] = deal(k1_nonD, k2_nonD, ...
                k3_nonD, v1_nonD, r1_nonD, r2_nonD, a_nonD, b_nonD, t2_nonD);

            app.k1Label.Text = num2str(k1_nonD);
            app.v1Label.Text = num2str(v1_nonD);
            app.r1Label.Text = num2str(r1_nonD);
            app.r2Label.Text = num2str(r2_nonD);
            app.k2Label.Text = num2str(k2_nonD);
            app.k3Label.Text = num2str(k3_nonD);
            app.aLabel.Text = num2str(a_nonD);
            app.bLabel.Text = num2str(b_nonD);
            app.t2Label.Text = num2str(t2_nonD);
            
            % Inhibitor improvement state: 23_[K3]_IR...
            [T,Y] = model_database('K3_IR_v3_2', k1, k2, k3, v1, r1, r2, a, b, t2, inits);


            % Finding lag time
            [peaks_locs, transition_point] = lag_time_calc(T,Y(:,5));
            peaks = peaks_locs(:,1);
            locs = peaks_locs(:,2);
            
            % current_text = app.LagsTextArea.Value;
            % new_text = [current_text,"1"];
            % app.LagsTextArea.Value = new_text;


            % Plot mineralization
            hold(app.UIAxes_2, 'on')
            plot(app.UIAxes_2, T,Y(:,5),'LineWidth',1);
            %scatter(app.UIAxes_2, transition_point, Y(locs(1)+1, 5) , "filled", "DisplayName", string(transition_point)); 
            scatter(app.UIAxes_2, T(locs+1), Y(locs+1, 5) , "filled", "DisplayName", string(transition_point)); 
            text(app.UIAxes_2, T(locs+1), Y(locs+1, 5) , string(round(T(locs+1))), 'VerticalAlignment', 'top');
            %legend(app.UIAxes_2);

            hold(app.UIAxes, 'on')
            plot(app.UIAxes, T,(Y(:,5)/max(Y(:,5))),'LineWidth',1, "DisplayName", "Mineral");
            [peaks_locs_2, transition_point_2] = lag_time_calc(T,(Y(:,5)/max(Y(:,5))));
            peaks_2 = peaks_locs_2(:,1);
            locs_2 = peaks_locs_2(:,2);
            scatter(app.UIAxes, transition_point_2, (Y(locs_2(1)+1, 5)/max(Y(:,5))) , "filled", "DisplayName", string(transition_point)); 
            text(app.UIAxes, transition_point_2, Y(locs_2(1)+1, 5) , string(round(transition_point)), 'VerticalAlignment', 'top');
            %legend(app.UIAxes);
            hold(app.UIAxes, 'off')


            % plot the rest 
            % plot of inhibitors - normalized:
            hold(app.UIAxes_3, 'on')
            plot(app.UIAxes_3,T,(Y(:,3)/max(Y(:,3))),'LineWidth',1);
            % plot of inhibitors - non-normalized:
            hold(app.UIAxes_8, 'on')
            plot(app.UIAxes_8,T,Y(:,3),'LineWidth',1) ;
            
            % plot of nucleators - normalized:
            hold(app.UIAxes_4, 'on')
            plot(app.UIAxes_4,T,(Y(:,4)/max(Y(:,4))),'LineWidth',1) ;
            % plot of nucleators - non-normalized:
            hold(app.UIAxes_7, 'on')
            plot(app.UIAxes_7,T,Y(:,4),'LineWidth',1) ;


            % plot of collagens - normalized:
            hold(app.UIAxes_5, 'on')
            plot(app.UIAxes_5,T,(Y(:,1)/max(Y(:,1))),'LineWidth',1) ;
            plot(app.UIAxes_5,T,(Y(:,2)/max(Y(:,2))),'LineWidth',1) ;
            
            % plot all together
            hold(app.UIAxes_9, 'on')
            plot(app.UIAxes_9, T,Y(:,1),'LineWidth',1);
            plot(app.UIAxes_9, T,Y(:,2),'LineWidth',1);
            plot(app.UIAxes_9, T,Y(:,3),'LineWidth',1);
            plot(app.UIAxes_9, T,Y(:,4),'LineWidth',1);
            plot(app.UIAxes_9, T,Y(:,5),'LineWidth',1);
            %legend(app.UIAxes_9);
            hold(app.UIAxes_9, 'off')


            if app.K3_IR_v3_2Button.Value == 1
                
                % Use the gradient function to calculate derivatives
                %dydt_values = gradient(Y(:,5), T);
                dy_dx = diff(Y(:,5)) ./ diff(T);
                hold(app.UIAxes_6, 'on')
                plot(app.UIAxes_6, T(2:end), dy_dx,'LineWidth',1);
                scatter(app.UIAxes_6,T(locs(1)+1), dy_dx(locs) ,'r', 'filled');


                %legend(app.UIAxes_6);
                
            end 
            
            
            if app.ExportdataaftereachrunCheckBox.Value == 1
                
                ISF_info_item = ["TCO3","TPO4","TCa","TMg","TNa","TCl","TK","pH",];
                ISF_info_value = [app.TCO3EditField.Value, app.TPO4EditField.Value, ...
                    app.TCaEditField.Value, app.TMgEditField.Value, app.TNaEditField.Value, ...
                    app.TClEditField.Value, app.TKEditField.Value, app.pHEditField.Value];
                ISF_info = [ISF_info_item', ISF_info_value'];
                
                Bio_info_item = ["x1_0", "x2_0", "I_0", "N_0", "y_0", "k1",...
                    "k2", "k3", "v1", "r1", "r2", "t2", "a", "b"];
                Bio_info_value = [ inits ,k1, k2, k3, v1, r1, r2, t2, a, b];
                Bio_info = [Bio_info_item', Bio_info_value'];
                lag_info = [T(locs+1), Y(locs+1, 5)];

                filename = 'data.xlsx';              
                sheetname = datestr(datetime('now'), 'yyyymmdd_HHMMSS');                
                
                xlswrite(filename, ["Mode:",app.DimSwitch.Value], sheetname, "A1");

                xlswrite(filename, ["ISF model Parameters:",""], sheetname, "A3");
                xlswrite(filename, ISF_info, sheetname, "A4");

                xlswrite(filename, ["Biological model Parameters:",""], sheetname, "A15");
                xlswrite(filename, Bio_info, sheetname, "A16");

                xlswrite(filename, ["Lag time info: [t, Y]",""], sheetname, "A32");
                xlswrite(filename, lag_info, sheetname, "A33");
                
                xlswrite(filename, ["t", "x1", "x2", "I", "N", "y"], sheetname, "D1");
                xlswrite(filename, [T,Y], sheetname, "D2");

                xlswrite(filename, ["dy/dt",""], sheetname, "J1");
                xlswrite(filename, dy_dx, sheetname, "J2");


            end


        end

        % Button pushed function: ClearplotsButton
        function ClearplotsButtonPushed(app, event)
            cla(app.UIAxes_2);
            %app.UIAxes_2.YLim = [0,1];
            cla(app.UIAxes);
            app.UIAxes.YLim = [-0.2,1.2];
            cla(app.UIAxes_3);
            app.UIAxes_3.YLim = [-0.1,1.2];
            cla(app.UIAxes_4);
            app.UIAxes_4.YLim = [-0.1,1.2];
            cla(app.UIAxes_5);
            app.UIAxes_5.YLim = [-0.1,1.2];
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);
            
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            
            % ISF values
            app.pHEditField.Value = 7.4;
            app.TCO3EditField.Value = 0.027;
            app.TPO4EditField.Value = 0.001;
            app.TCaEditField.Value = 0.0013; %0.0016;
            app.TMgEditField.Value = 0.001;
            app.TNaEditField.Value = 0.142;
            app.TClEditField.Value = 0.103;
            app.TKEditField.Value = 0.005;
            
            % Biomin parameter values
            app.k1EditField.Value = 0.1;
            app.k2EditField.Value = 1;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.1;
            app.r1EditField.Value = 2e-7;
            app.r2EditField.Value = 1.7e-8;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            app.t2EditField.Value = 1e-9;


            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 1e6;    
            app.NEditField.Value = 10;    
            app.YEditField.Value = 0; 
            
            % characteristic values
            app.x1_cEditField.Value = 10^6; 
            app.x2_cEditField.Value = app.x1_cEditField.Value; %assumption
            app.I_cEditField.Value = 10^6;
            app.N_cEditField.Value = 10^4;
            app.Y_cEditField.Value = 10^10;


        end

        % Button pushed function: combin1Button
        function combin1ButtonPushed(app, event)

            % Biomin parameter values
            app.k1EditField.Value = 0.4;
            app.k2EditField.Value = 0.0005;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.01;
            app.r1EditField.Value = 5e-8;
            app.r2EditField.Value = 5e-9;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            app.t2EditField.Value = 5e-9;


            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 1e6;    
            app.NEditField.Value = 10;    
            app.YEditField.Value = 0; 


        end

        % Button pushed function: ChosenmodeButton
        function ChosenmodeButtonPushed(app, event)
            % Biomin parameter values
            app.k1EditField.Value = 0.3;
            app.k2EditField.Value = 0.005;
            app.k3EditField.Value = 292855.1449679231;
            app.v1EditField.Value = 0.005;
            app.r1EditField.Value = 5e-8;
            app.r2EditField.Value = 5e-10;
            app.aEditField.Value = 10;
            app.bEditField.Value = 1e57;
            app.t2EditField.Value = 1e-9; %5e-11;


            % Biomin variable initial values
            app.X1EditField.Value = 9.4e5;   
            app.X2EditField.Value = 0;  
            app.IEditField.Value = 9.4e5;    
            app.NEditField.Value = 10;    
            app.YEditField.Value = 0; 
            
        end

        % Button pushed function: Not2Button
        function Not2ButtonPushed(app, event)
            app.t2EditField.Value = 0;
            app.r1EditField.Value = 1e-7;
        end

        % Button pushed function: Nor1Button
        function Nor1ButtonPushed(app, event)
            app.t2EditField.Value = 8e-9;
            app.r1EditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mineralization - Normalized')
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Mineralization')
            xlabel(app.UIAxes_2, 'Day')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Inhibotors')
            xlabel(app.UIAxes_3, 'Day')
            ylabel(app.UIAxes_3, 'I')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Nucleators')
            xlabel(app.UIAxes_4, 'Day')
            ylabel(app.UIAxes_4, 'N')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Collagen')
            xlabel(app.UIAxes_5, 'Day')
            ylabel(app.UIAxes_5, 'Xi')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'dY/dt')
            xlabel(app.UIAxes_6, 'Day')
            ylabel(app.UIAxes_6, 'dy/dt')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Nucleators')
            xlabel(app.UIAxes_7, 'Day')
            ylabel(app.UIAxes_7, 'N')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Inhibotors')
            xlabel(app.UIAxes_8, 'Day')
            ylabel(app.UIAxes_8, 'I')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'All - normalized')
            xlabel(app.UIAxes_9, 'Day')
            ylabel(app.UIAxes_9, 'N')
            zlabel(app.UIAxes_9, 'Z')
            app.PhysicochemicalParametersLabel = uilabel(app.UIFigure);
            app.PhysicochemicalParametersLabel.Text = 'Physicochemical Parameters';
            app.BiomineralizationParametersLabel = uilabel(app.UIFigure);
            app.BiomineralizationParametersLabel.Text = 'Biomineralization Parameters';
            app.TCO3EditFieldLabel = uilabel(app.UIFigure);
            app.TCO3EditFieldLabel.Text = 'TCO3';
            app.TCO3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TCO3EditField.Value = 0.027;
            app.TPO4EditFieldLabel = uilabel(app.UIFigure);
            app.TPO4EditFieldLabel.Text = 'TPO4';
            app.TPO4EditField = uieditfield(app.UIFigure, 'numeric');
            app.TPO4EditField.Value = 0.001;
            app.TCaEditFieldLabel = uilabel(app.UIFigure);
            app.TCaEditFieldLabel.Text = 'TCa';
            app.TCaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TCaEditField.Value = 0.0016;
            app.TMgEditFieldLabel = uilabel(app.UIFigure);
            app.TMgEditFieldLabel.Text = 'TMg';
            app.TMgEditField = uieditfield(app.UIFigure, 'numeric');
            app.TMgEditField.Value = 0.001;
            app.TNaEditFieldLabel = uilabel(app.UIFigure);
            app.TNaEditFieldLabel.Text = 'TNa';
            app.TNaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TNaEditField.Value = 0.142;
            app.TClEditFieldLabel = uilabel(app.UIFigure);
            app.TClEditFieldLabel.Text = 'TCl';
            app.TClEditField = uieditfield(app.UIFigure, 'numeric');
            app.TClEditField.Value = 0.103;
            app.TKEditFieldLabel = uilabel(app.UIFigure);
            app.TKEditFieldLabel.Text = 'TK';
            app.TKEditField = uieditfield(app.UIFigure, 'numeric');
            app.TKEditField.Value = 0.005;
            app.pHEditFieldLabel = uilabel(app.UIFigure);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.UIFigure, 'numeric');
            app.pHEditField.Value = 7.4;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 940000;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = 1000000;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 10;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.k1EditFieldLabel = uilabel(app.UIFigure);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.UIFigure, 'numeric');
            app.k1EditField.Value = 0.1;
            app.v1EditFieldLabel = uilabel(app.UIFigure);
            app.v1EditFieldLabel.Text = 'v1';
            app.v1EditField = uieditfield(app.UIFigure, 'numeric');
            app.v1EditField.Value = 0.1;
            app.r1EditFieldLabel = uilabel(app.UIFigure);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r1EditField.Value = 2e-07;
            app.k2EditFieldLabel = uilabel(app.UIFigure);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.UIFigure, 'numeric');
            app.k2EditField.Value = 1;
            app.r2EditFieldLabel = uilabel(app.UIFigure);
            app.r2EditFieldLabel.Text = 'r2';
            app.r2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2EditField.Value = 1.7e-08;
            app.k3EditFieldLabel = uilabel(app.UIFigure);
            app.k3EditFieldLabel.Text = 'k3';
            app.k3EditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 10;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 1e+57;
            app.RunISFButton = uibutton(app.UIFigure, 'push');
            app.RunISFButton.ButtonPushedFcn = createCallbackFcn(app, @RunISFButtonPushed, true);
            app.RunISFButton.Text = 'Run ISF';
            app.RateUnitLabel = uilabel(app.UIFigure);
            app.RateUnitLabel.Text = 'Rate Unit';
            app.RateEditFieldLabel = uilabel(app.UIFigure);
            app.RateEditFieldLabel.Text = 'Rate ';
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunmineralizationButton = uibutton(app.UIFigure, 'push');
            app.RunmineralizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunmineralizationButtonPushed, true);
            app.RunmineralizationButton.Text = 'Run mineralization';
            app.x1_cEditFieldLabel = uilabel(app.UIFigure);
            app.x1_cEditFieldLabel.Text = 'x1_c';
            app.x1_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.x1_cEditField.Value = 1000000;
            app.x2_cEditFieldLabel = uilabel(app.UIFigure);
            app.x2_cEditFieldLabel.Text = 'x2_c';
            app.x2_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.x2_cEditField.Value = 1000000;
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1';
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2';
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Text = 'I';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.k1Label = uilabel(app.UIFigure);
            app.k1Label.Text = 'k1';
            app.v1Label = uilabel(app.UIFigure);
            app.v1Label.Text = 'v1';
            app.r1Label = uilabel(app.UIFigure);
            app.r1Label.Text = 'r1';
            app.k2Label = uilabel(app.UIFigure);
            app.k2Label.Text = 'k2';
            app.r2Label = uilabel(app.UIFigure);
            app.r2Label.Text = 'r2';
            app.k3Label = uilabel(app.UIFigure);
            app.k3Label.Text = 'k3';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.ClearplotsButton = uibutton(app.UIFigure, 'push');
            app.ClearplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotsButtonPushed, true);
            app.ClearplotsButton.Text = 'Clear plots';
            app.DefaultValuesButton = uibutton(app.UIFigure, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = 'Default Values';
            app.DimSwitch = uiswitch(app.UIFigure, 'slider');
            app.DimSwitch.Items = {'Non-dim', 'Dim'};
            app.DimSwitch.Value = 'Non-dim';
            app.ModelSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ModelSelectionButtonGroup.Title = 'Model Selection:';
            app.K3_IR_v3_2Button = uiradiobutton(app.ModelSelectionButtonGroup);
            app.K3_IR_v3_2Button.Text = 'K3_IR_v3_2';
            app.K3_IR_v3_2Button.Value = true;
            app.I_cEditFieldLabel = uilabel(app.UIFigure);
            app.I_cEditFieldLabel.Text = 'I_c';
            app.I_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_cEditField.Value = 1000000;
            app.N_cEditFieldLabel = uilabel(app.UIFigure);
            app.N_cEditFieldLabel.Text = 'N_c';
            app.N_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.N_cEditField.Value = 10000;
            app.Y_cEditFieldLabel = uilabel(app.UIFigure);
            app.Y_cEditFieldLabel.Text = 'Y_c';
            app.Y_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_cEditField.Value = 10000000000;
            app.t2EditFieldLabel = uilabel(app.UIFigure);
            app.t2EditFieldLabel.Text = 't2';
            app.t2EditField = uieditfield(app.UIFigure, 'numeric');
            app.t2EditField.Value = 2e-07;
            app.t2Label = uilabel(app.UIFigure);
            app.t2Label.Text = 't2';
            app.combin1Button = uibutton(app.UIFigure, 'push');
            app.combin1Button.ButtonPushedFcn = createCallbackFcn(app, @combin1ButtonPushed, true);
            app.combin1Button.Text = 'combin. 1';
            app.ChosenmodeButton = uibutton(app.UIFigure, 'push');
            app.ChosenmodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChosenmodeButtonPushed, true);
            app.ChosenmodeButton.Text = 'Chosen mode';
            app.ExportdataaftereachrunCheckBox = uicheckbox(app.UIFigure);
            app.ExportdataaftereachrunCheckBox.Text = 'Export data after each run';
            app.Not2Button = uibutton(app.UIFigure, 'push');
            app.Not2Button.ButtonPushedFcn = createCallbackFcn(app, @Not2ButtonPushed, true);
            app.Not2Button.Text = 'No t2';
            app.Nor1Button = uibutton(app.UIFigure, 'push');
            app.Nor1Button.ButtonPushedFcn = createCallbackFcn(app, @Nor1ButtonPushed, true);
            app.Nor1Button.Text = 'No r1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mineralization_tool_Investigation_mode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end